classdef Client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        hash        matlab.ui.control.Label
        parasite4   matlab.ui.control.NumericEditField
        Label_6     matlab.ui.control.Label
        parasite3   matlab.ui.control.NumericEditField
        Label_5     matlab.ui.control.Label
        parasite2   matlab.ui.control.NumericEditField
        Label_4     matlab.ui.control.Label
        parasite1   matlab.ui.control.NumericEditField
        Label_3     matlab.ui.control.Label
        Image2      matlab.ui.control.Image
        codeButton  matlab.ui.control.Button
        name        matlab.ui.control.DropDown
        Label_2     matlab.ui.control.Label
        CheckImage  matlab.ui.control.Button
        Image       matlab.ui.control.Image
        Label       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("..\信息安全作品赛\"));
            IOTClientGetParams(app);
        end

        % Button pushed function: CheckImage
        function CheckImageButtonPushed(app, event)
            
            filename = app.name.Value;
            imname = [filename '.png'];
            grayImg = imread(imname);
            if size(grayImg, 3) == 1
                rgbImg = cat(3, grayImg, grayImg, grayImg);
            else
                rgbImg = grayImg;
            end
            app.Image.ImageSource = rgbImg;
        end

        % Button pushed function: codeButton
        function codeButtonPushed(app, event)
            filename = app.name.Value;
            imname = [filename '.png'];
            grayImg = imread(imname);
            x01 = app.parasite1.Value;
            mu1 = app.parasite2.Value;
            x02 = app.parasite3.Value;
            mu2 = app.parasite4.Value;

            [Mhash,matrixToSend] = IOTClient(x01,mu1,x02,mu2,grayImg);
            imagecode = uint8(matrixToSend);
            rgbcode = cat(3,imagecode,imagecode,imagecode);
            app.hash.Text = Mhash;
            app.Image2.ImageSource = rgbcode;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '模拟边缘网络设备';
            app.Image = uiimage(app.UIFigure);
            app.CheckImage = uibutton(app.UIFigure, 'push');
            app.CheckImage.ButtonPushedFcn = createCallbackFcn(app, @CheckImageButtonPushed, true);
            app.CheckImage.Text = 'Check';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '图片选择';
            app.name = uidropdown(app.UIFigure);
            app.name.Items = {'lena', 'boats', 'cameraman', 'fingerprint', 'foreman', 'house', 'Monarch', 'Parrots'};
            app.name.Value = 'lena';
            app.codeButton = uibutton(app.UIFigure, 'push');
            app.codeButton.ButtonPushedFcn = createCallbackFcn(app, @codeButtonPushed, true);
            app.codeButton.Text = 'code';
            app.Image2 = uiimage(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '参数1';
            app.parasite1 = uieditfield(app.UIFigure, 'numeric');
            app.parasite1.Value = 0.459;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '参数2';
            app.parasite2 = uieditfield(app.UIFigure, 'numeric');
            app.parasite2.Value = 0.372;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '参数3';
            app.parasite3 = uieditfield(app.UIFigure, 'numeric');
            app.parasite3.Value = 0.281;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '参数4';
            app.parasite4 = uieditfield(app.UIFigure, 'numeric');
            app.parasite4.Value = 0.487;
            app.hash = uilabel(app.UIFigure);
            app.hash.Text = 'hash:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Client

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end