classdef IOTImageSecureTransmission < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        HashLabel  matlab.ui.control.Label
        name       matlab.ui.control.DropDown
        Label_2    matlab.ui.control.Label
        Button_2   matlab.ui.control.Button
        Button     matlab.ui.control.Button
        Label      matlab.ui.control.Label
        encode     matlab.ui.control.UIAxes
        cord       matlab.ui.control.UIAxes
        Soure      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            filename = app.name.Value;
            imname = [filename '.png'];
            image = imread(imname);
            imshow(image,'Parent',app.Soure);
   
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %  嵌入NINPT的并行压缩感知（解决KPA问题）

%  A test example
                        %    自编函数

%%  — — — — —  — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — —

%  测试图像选择 ( dataset11)

filename = app.name.Value;                                  %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'barbara';                         %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'boats';                            %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'cameraman';                  %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'foreman';                       %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'house';                           %    图像文件名（这样做的好处在于实验时更改方便）

% filename = 'Monarch';                      %    图像文件名（这样做的好处在于实验时更改方便）
 
% filename = 'Parrots';                         %    图像文件名（这样做的好处在于实验时更改方便）

%  — — — — —  — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — —

%%  — — — — —  — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — — —
%  参数设置

x01 =0.759; x02 =0.581; x03 =0.652; x04 =0.385; 
mu1 = 6.372; mu2 = 7.687; mu3 = 5.193; mu4 = 6.471; 
v = 0.15;
h = 0.45;
T0 = 1000;
d = 10;

subrate=0.5;                                         %     欠采样率

a = 0;                                                    %     噪声控制因子

quantizer_bitdepth = 6;                       %     比特深度

num_trial = 1;                                       %     重复试验次数

num_levels = 3;                                    %     小波分解的水平数

max_iterations = 3;                           %     最大迭代次数
 
size_images = 256;                               %     图像维度，考虑方阵

size_down_images = 32;

total_pixels = size_images * size_images;          %    总像素个数

% 读取图像

original_filename = [ filename '.png'];                                    %     存储图像变量

original_image = double (imread(original_filename));        %      读图像

[num_rows, num_cols] = size(original_image);                    %      计算图像的行和列

downsamping_image = imresize(imread(original_filename), [size_down_images size_down_images]);      %    获得欠采样子图

interpolated_image = double (imresize(downsamping_image, [size_images size_images], 'bilinear'));       %    获得插值图像

%%  加密阶段

%  注入噪声的NPT加密

%  生成一个注入的噪声信号

key_matrix = key_matrix_generate (num_rows, num_cols);

%  NPT加密矩阵 

% coffused_image = 0.9 * carrier_image + 0.1 * original_image;
% 
NPT_model = NPTmodel (num_rows, num_cols); 
% 
encrypted_image  = NINPT_encryption (original_image, num_rows, num_cols, a, key_matrix, NPT_model);


% % 随机模矩阵
% 
% key_matrix = key_matrix_generate (num_rows, num_cols);
% 
% %  模加密
% 
% encrypted_image = mod_encrypting  (original_image, key_matrix, size_images);
% 

%  模解密

% decrypted_image = mod_decrypting (encrypted_image, key_matrix, size_images);

%  灰度映射操作

imean = mean(encrypted_image (:) );

for i=1:1:size_images
    
    for j =1:1:size_images
        
    mean_image (i, j)  =  imean;

    end
    
end

% mean_encrypted_image = encrypted_image - mean_image;       %   灰度映射后的密文图像

% decrypted_image  = NINPT_decryption (encrypted_image, num_rows, num_cols, a, key_matrix, NPT_model);

%%  压缩感知测量和重构

 for k = 1: 1 : 1
           
     subrate = 0.6;
        
     %  测量矩阵生成
     
     %M = round (subrate * num_rows);                                %    测量值个数
     
     %Phi = orth (randn(size_images, size_images))';             %    生成测量矩阵

     %Phi = Phi (1:M, :);  
     
     phi1sequence = FCCM( x01, mu1 , v, h, d*154*256 );
     Phi = matrix(phi1sequence, d, 154, 256);

     %  CS测量
    
     tic;
              
     y =  Phi  *  encrypted_image;                        %    测量值计算
     
     y_mean =  Phi  *  mean_image;                        %    测量值计算
     
     y_diff = y - y_mean;
       
     toc;    
     

%  测量值量化


[y_sq, rate_sq] = SQ_Coding (y_diff, quantizer_bitdepth, num_rows, num_cols);                    %   普通熵编码

yfinal = y_sq + y_mean;


%  CS重构

 initial = pinv(Phi) * yfinal;

reconstructed_image = PCS_PL_ED_Decoder (yfinal, Phi, key_matrix,  NPT_model , a, num_rows, num_cols, num_levels, max_iterations);

% reconstructed_image = PCS_PL_ED_Decoder_mod (yfinal, interpolated_image, Phi, key_matrix, num_rows, num_cols, num_levels, max_iterations);


% [PSNR, reconstructed_image]  = ...
%     PCS_PL_ED_Decoder_shoulian (y, original_image, 1, Phi, key_matrix, NPT_model, a, num_rows, num_cols, num_levels, 500);
 
PSNRfinal (k) = psnr(uint8(reconstructed_image), uint8(original_image));                     %    没有滤波的峰值信噪比

rate (k) = rate_sq;                     %    没有滤波的峰值信噪比


 end


   imshow(uint8(reconstructed_image),'Parent',app.encode);
   
       [Y_q, ~,  ~]= quantization_255(y); 
   imshow(uint8(Y_q),'Parent',app.cord)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Soure = uiaxes(app.UIFigure);
            title(app.Soure, 'Soure')
            app.Soure.XTick = [];
            app.Soure.YTick = [];
            app.cord = uiaxes(app.UIFigure);
            title(app.cord, 'cord')
            app.cord.XTick = [];
            app.cord.YTick = [];
            app.encode = uiaxes(app.UIFigure);
            title(app.encode, 'encode')
            app.encode.XTick = [];
            app.encode.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '基于压缩感知与混沌序列的图像安全加密系统';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '加密';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '图片选择';
            app.name = uidropdown(app.UIFigure);
            app.name.Items = {'lena', 'barbara', 'boats', 'cameraman'};
            app.name.Value = 'lena';
            app.HashLabel = uilabel(app.UIFigure);
            app.HashLabel.Text = 'Hash:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IOTImageSecureTransmission

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end