classdef Server < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        hash       matlab.ui.control.Label
        parasite4  matlab.ui.control.NumericEditField
        Label_5    matlab.ui.control.Label
        parasite3  matlab.ui.control.NumericEditField
        Label_4    matlab.ui.control.Label
        parasite2  matlab.ui.control.NumericEditField
        Label_3    matlab.ui.control.Label
        parasite1  matlab.ui.control.NumericEditField
        Label_2    matlab.ui.control.Label
        Button     matlab.ui.control.Button
        Image      matlab.ui.control.Image
        Label      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("..\信息安全作品赛\"));
            IOTSeverGetParams(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [Mhash,reimage] = IOTServer(app);
            imagecode = uint8(reimage);
            rgbcode = cat(3,imagecode,imagecode,imagecode);
            app.Image.ImageSource = rgbcode;
            app.hash.Text = char(Mhash);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '模拟终端设备';
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '监听';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '参数1';
            app.parasite1 = uieditfield(app.UIFigure, 'numeric');
            app.parasite1.Value = 0.459;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '参数2';
            app.parasite2 = uieditfield(app.UIFigure, 'numeric');
            app.parasite2.Value = 0.372;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '参数3';
            app.parasite3 = uieditfield(app.UIFigure, 'numeric');
            app.parasite3.Value = 0.281;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '参数4';
            app.parasite4 = uieditfield(app.UIFigure, 'numeric');
            app.parasite4.Value = 0.487;
            app.hash = uilabel(app.UIFigure);
            app.hash.Text = 'hash:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Server

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end